classdef Image_Labeling_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageLabelerAppUIFigure    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        DoneButton                 matlab.ui.control.Button
        NextImageButton            matlab.ui.control.Button
        ImageContentsButtonGroup   matlab.ui.container.ButtonGroup
        InflammationButton         matlab.ui.control.RadioButton
        MostlyBackgroundButton     matlab.ui.control.RadioButton
        SmoothMuscleButton         matlab.ui.control.RadioButton
        GlomerulusButton           matlab.ui.control.RadioButton
        TubulesInterstitiumButton  matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Image_Dir
        Image_N
        Image_Paths
        Current_Image
        Save_File
        Labels

    end
    
    methods (Access = public)
        
        function Update_Labels(app)

            if isempty(app.Labels)
                app.Labels.Image_Names = {app.Current_Image.Name};
                app.Labels.Labels = {app.Current_Image.Label};

            else
                app.Labels.Image_Names = [app.Labels.Image_Names;{app.Current_Image.Name}];
                app.Labels.Labels = [app.Labels.Labels;{app.Current_Image.Label}];

            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Get directory of images to label:
            app.Image_Dir = uigetdir(pwd,"Pick the folder containing your images");

            app.Save_File = strcat(app.Image_Dir,filesep,'Image_Labels.csv');

            % Getting contents of Image_Dir
            image_paths = dir(app.Image_Dir);
            image_paths = image_paths(~ismember({image_paths.name},{'.','..'}));
            image_paths = image_paths(contains({image_paths.name},{'.jpg','.png','.jpeg','.tif'}));
            app.Image_Paths = {image_paths.name};

            app.Image_N = length(app.Image_Paths);
            
            app.Current_Image.N = 1;
            app.Current_Image.Name = app.Image_Paths{1};
            app.Current_Image.Image = imread(strcat(app.Image_Dir,filesep,app.Image_Paths{1}));
            app.Current_Image.Label = app.ImageContentsButtonGroup.SelectedObject.Text;

            imshow(app.Current_Image.Image,'Parent',app.UIAxes)
            title(app.UIAxes,strcat('Image Name:',app.Current_Image.Name,', Remaining:',num2str(app.Image_N-app.Current_Image.N)))
       

        end

        % Selection changed function: ImageContentsButtonGroup
        function ImageContentsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageContentsButtonGroup.SelectedObject;
            
            app.Current_Image.Label = selectedButton.Text;

            
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            % Going to the next image in the directory
            app.Current_Image.N = app.Current_Image.N + 1;

            Update_Labels(app)

            if app.Current_Image.N < length(app.Image_Paths)
                app.Current_Image.Name = app.Image_Paths{app.Current_Image.N};
                app.Current_Image.Image = imread(strcat(app.Image_Dir,filesep,app.Current_Image.Name));
                app.Current_Image.Label = app.ImageContentsButtonGroup.SelectedObject.Text;

                imshow(app.Current_Image.Image,'Parent',app.UIAxes)
                title(app.UIAxes,strcat('Image Name: ',app.Current_Image.Name,', Remaining: ',num2str(app.Image_N-app.Current_Image.N)))
            
            elseif app.Current_Image.N == length(app.Image_Paths)
                app.NextImageButton.Enable = 'off';

                app.Current_Image.Name = app.Image_Paths{app.Current_Image.N};
                app.Current_Image.Image = imread(strcat(app.Image_Dir,filesep,app.Current_Image.Name));
                app.Current_Image.Label = app.ImageContentsButtonGroup.SelectedObject.Text;

                imshow(app.Current_Image.Image,'Parent',app.UIAxes)
                title(app.UIAxes,strcat('Image Name: ',app.Current_Image.Name,', Remaining: ',num2str(app.Image_N-app.Current_Image.N)))
            
            end


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % Writing labels to csv file
            label_table = struct2table(app.Labels);
            writetable(label_table,app.Save_File)
            delete(app)


        end

        % Close request function: ImageLabelerAppUIFigure
        function ImageLabelerAppUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ImageLabelerAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {424, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageLabelerAppUIFigure = uifigure('Visible', 'off');
            app.ImageLabelerAppUIFigure.Name = 'Image Labeler App';
            app.ImageLabelerAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageLabelerAppUIFigureCloseRequest, true);
            app.ImageLabelerAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ImageLabelerAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Image Name: ')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageContentsButtonGroup = uibuttongroup(app.RightPanel);
            app.ImageContentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageContentsButtonGroupSelectionChanged, true);
            app.ImageContentsButtonGroup.Title = 'Image Contents:';
            app.TubulesInterstitiumButton = uiradiobutton(app.ImageContentsButtonGroup);
            app.TubulesInterstitiumButton.Text = 'Tubules & Interstitium';
            app.TubulesInterstitiumButton.Value = true;
            app.GlomerulusButton = uiradiobutton(app.ImageContentsButtonGroup);
            app.GlomerulusButton.Text = 'Glomerulus';
            app.SmoothMuscleButton = uiradiobutton(app.ImageContentsButtonGroup);
            app.SmoothMuscleButton.Text = 'Smooth Muscle';
            app.MostlyBackgroundButton = uiradiobutton(app.ImageContentsButtonGroup);
            app.MostlyBackgroundButton.Text = 'Mostly Background';
            app.InflammationButton = uiradiobutton(app.ImageContentsButtonGroup);
            app.InflammationButton.Text = 'Inflammation';
            app.NextImageButton = uibutton(app.RightPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.DoneButton = uibutton(app.RightPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.ImageLabelerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Labeling_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageLabelerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageLabelerAppUIFigure)
        end
    end
end